% description: Cocoabox is a library of software modules for modeling and analysis of complex systems, implemented in MATLAB. It makes the analysis of complex systems simple by combining a set of tools
% for advanced data analysis usually available in separate libraries (dimension reduction, visualization, estimation of linear and nonlinear dependence, theoretical analysis of graphs, and more). Its
% modular architecture makes it easily adaptable to the needs of various application areas.
% The documentation and latest version is available at https://github.com/cobragroup/cocoabox.
% The full list of authors is available in the README file.
% Copyright (c) 2020, Institute of Computer Science of the Czech Academy of  Sciences
% This software is made available under the AGPL 3.0 licence. For license details see the LICENSE file. For other licensing options including more permissive licenses, please contact the first author
% (hlinka@cs.cas.cz) or email licensing@cs.cas.cz.
% If using this software for academic work, please cite it in your publications as:
% Hlinka, J. et al. (2020) Cocoabox, GitHub repository, https://github.com/cobragroup/cocoabox.
classdef cocoabox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CocoaboxUIFigure                matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataimportTab                   matlab.ui.container.Tab
        GridLayoutDataimport            matlab.ui.container.GridLayout
        DatasourceDropDown              matlab.ui.control.DropDown
        ImportdataButton                matlab.ui.control.Button
        DatasourceDropDownLabel         matlab.ui.control.Label
        ImportmatrixmatricesButton      matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        NumberofmatricesEditField       matlab.ui.control.NumericEditField
        NumberoftimepointsEditField     matlab.ui.control.NumericEditField
        NumberofregionsEditField        matlab.ui.control.NumericEditField
        NumberofrealisationsEditField   matlab.ui.control.NumericEditField
        NumberoftimepointsEditFieldLabel  matlab.ui.control.Label
        NumberofregionsEditFieldLabel   matlab.ui.control.Label
        NumberofrealisationsEditFieldLabel  matlab.ui.control.Label
        GraphdensityEditField           matlab.ui.control.NumericEditField
        GraphdensityEditFieldLabel      matlab.ui.control.Label
        LargesteigenvalueEditField      matlab.ui.control.NumericEditField
        LargesteigenvalueEditFieldLabel  matlab.ui.control.Label
        NumberofmatricesEditFieldLabel  matlab.ui.control.Label
        symmetricCheckBox               matlab.ui.control.CheckBox
        autocorrelatedCheckBox          matlab.ui.control.CheckBox
        GeneratedataButton              matlab.ui.control.Button
        DatalabelingPanel               matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        DatasetlabelEditFieldLabel      matlab.ui.control.Label
        DatasetlabelEditField           matlab.ui.control.EditField
        Group1LabelEditFieldLabel       matlab.ui.control.Label
        Group1LabelEditField            matlab.ui.control.EditField
        Group2LabelEditFieldLabel       matlab.ui.control.Label
        CountEditField_Group1           matlab.ui.control.NumericEditField
        Group2LabelEditField            matlab.ui.control.EditField
        CountEditFieldLabel             matlab.ui.control.Label
        CountEditField_2Label           matlab.ui.control.Label
        CountEditField_Group2           matlab.ui.control.NumericEditField
        LabeldataButton                 matlab.ui.control.Button
        SubsetselectionPanel            matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        TimepointsEditField_Subset      matlab.ui.control.EditField
        TimepointsSwitch_Subset         matlab.ui.control.Switch
        RegionsEditField_Subset         matlab.ui.control.EditField
        RegionsSwitch_Subset            matlab.ui.control.Switch
        RealisationsEditField_Subset    matlab.ui.control.EditField
        RealisationsSwitch_Subset       matlab.ui.control.Switch
        TimepointsEditField_4Label      matlab.ui.control.Label
        RegionsEditField_4Label         matlab.ui.control.Label
        RealisationsEditFieldLabel      matlab.ui.control.Label
        SelectsubsetButton_Subset       matlab.ui.control.Button
        ResetButton_Subset              matlab.ui.control.Button
        DimensionalityreductionTab      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        MethodDropDown_DimRed           matlab.ui.control.DropDown
        MethodDropDown_6Label           matlab.ui.control.Label
        NumberofcomponentsEditField_DimRed  matlab.ui.control.NumericEditField
        NumberofcomponentsEditFieldLabel  matlab.ui.control.Label
        DimensionDropDown_DimRed        matlab.ui.control.DropDown
        DimensionDropDownLabel          matlab.ui.control.Label
        ReducedimensionalityButton_DimRed  matlab.ui.control.Button
        ResetButton_DimRed              matlab.ui.control.Button
        VisualisationPanel_2            matlab.ui.container.Panel
        GridLayout2_3                   matlab.ui.container.GridLayout
        MethodDropDownVis_DimRed        matlab.ui.control.DropDown
        TimepointsEditField_DimRed      matlab.ui.control.EditField
        TimepointsSwitch_DimRed         matlab.ui.control.Switch
        ComponentsEditField_DimRed      matlab.ui.control.EditField
        RegionsSwitch_DimRed            matlab.ui.control.Switch
        RealisationsEditField_DimRed    matlab.ui.control.EditField
        RealisationsSwitch_DimRed       matlab.ui.control.Switch
        TimepointsEditField_3Label      matlab.ui.control.Label
        ComponentsEditFieldLabel        matlab.ui.control.Label
        RealisationsEditField_5Label    matlab.ui.control.Label
        VisualiseButton_DimRed          matlab.ui.control.Button
        MethodDropDown_8Label           matlab.ui.control.Label
        ConnectivitymatricesTab         matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        ConnectivitymeasureDropDown_Matrices  matlab.ui.control.DropDown
        ComputeButton_Matrices          matlab.ui.control.Button
        ConnectivitymeasureDropDownLabel  matlab.ui.control.Label
        VisualisationPanel_Matrices     matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        OptionDropDown_Matrices         matlab.ui.control.DropDown
        OptionDropDownLabel             matlab.ui.control.Label
        MatricescountEditField_Matrices  matlab.ui.control.NumericEditField
        MatricesEditField_Matrices      matlab.ui.control.EditField
        MatricesEditFieldLabel          matlab.ui.control.Label
        VisualiseButton_Matrices        matlab.ui.control.Button
        MatricescountEditFieldLabel     matlab.ui.control.Label
        StatisticalinferenceTab         matlab.ui.container.Tab
        GridLayout8_2                   matlab.ui.container.GridLayout
        MethodDropDown_Stats            matlab.ui.control.DropDown
        GroupcomparisontestDropDown_Stats  matlab.ui.control.DropDown
        GroupcomparisontestDropDown_2Label  matlab.ui.control.Label
        MultiplecorrectionmethodDropDown_Stats  matlab.ui.control.DropDown
        MultiplecorrectionmethodDropDown_2Label  matlab.ui.control.Label
        alphathresholdEditField_Stats   matlab.ui.control.NumericEditField
        alphathresholdEditField_3Label  matlab.ui.control.Label
        FeatureDropDown_Stats           matlab.ui.control.DropDown
        FeatureDropDownLabel_Stats      matlab.ui.control.Label
        AdditionalmatrixLabel_Stats     matlab.ui.control.Label
        LoadmatrixButton_Stats          matlab.ui.control.Button
        ComputeButton_Stats             matlab.ui.control.Button
        VisualiseButton_Stats           matlab.ui.control.Button
        MethodDropDownLabel_2           matlab.ui.control.Label
        GraphtheoryanalysisTab          matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        ThresholddensityEditField_Graphs  matlab.ui.control.NumericEditField
        ThresholddensityEditFieldLabel  matlab.ui.control.Label
        alphathresholdEditField_GraphsLabel  matlab.ui.control.Label
        alphathresholdEditField_Graphs  matlab.ui.control.NumericEditField
        ComputeButton_Graphs            matlab.ui.control.Button
        VisualiseButton_Graphs          matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        Image2_9                        matlab.ui.control.Image
        Image10                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        
        oldpath = addpath('Data', 'Functions_cocoa', genpath('Functions_external')); % add folders with data and functions to path
        data % user imported data
        info % structure with global information
        results % structure for storing results
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %%% Set default interactivity for connectivity matrices visualisation     
            
            if app.OptionDropDown_Matrices.Value == "first matrix"
                app.MatricescountEditFieldLabel.Visible = 'on';
                app.MatricescountEditField_Matrices.Visible = 'on';
                app.MatricescountEditField_Matrices.Enable = 'off';
                app.MatricesEditFieldLabel.Visible = 'off';
                app.MatricesEditField_Matrices.Visible = 'off';
                app.MatricesEditField_Matrices.Enable = 'off';
            elseif app.OptionDropDown_Matrices.Value == "number of matrices"
                app.MatricescountEditFieldLabel.Visible = 'on';
                app.MatricescountEditField_Matrices.Visible = 'on';
                app.MatricescountEditField_Matrices.Enable = 'on';
                app.MatricesEditFieldLabel.Visible = 'off';
                app.MatricesEditField_Matrices.Visible = 'off';
                app.MatricesEditField_Matrices.Enable = 'off';
            elseif app.OptionDropDown_Matrices.Value == "selected matrices"
                app.MatricescountEditFieldLabel.Visible = 'off';
                app.MatricescountEditField_Matrices.Visible = 'off';
                app.MatricescountEditField_Matrices.Enable = 'off';
                app.MatricesEditFieldLabel.Visible = 'on';
                app.MatricesEditField_Matrices.Visible = 'on';
                app.MatricesEditField_Matrices.Enable = 'on';
            end
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)

            %%% Clear text area upon changing the tab
            
            app.TextArea.Value = '';
            
        end

        % Value changed function: DatasourceDropDown
        function DatasourceDropDownValueChanged(app, event)
          
            if app.DatasourceDropDown.Value == "import own data"
                app.ImportdataButton.Visible = 'on';
                app.ImportdataButton.Enable = 'on';
                app.ImportmatrixmatricesButton.Visible = 'off';
                app.ImportmatrixmatricesButton.Enable = 'off';
                app.ImportdataButton.Text = 'Import data';
                app.GridLayout11.Visible = 'off';
                
            elseif app.DatasourceDropDown.Value == "import matrix/matrices and generate data"
                app.ImportdataButton.Visible = 'off';
                app.ImportdataButton.Enable = 'off';                
                app.ImportmatrixmatricesButton.Visible = 'on';                
                app.ImportmatrixmatricesButton.Enable = 'on';                
                app.ImportdataButton.Text = 'Import matrix/matrices';
                app.GridLayout11.Visible = 'on';
                
                app.GraphdensityEditField.Visible = 'off';
                app.GraphdensityEditFieldLabel.Visible = 'off';
                app.LargesteigenvalueEditField.Visible = 'off';
                app.LargesteigenvalueEditFieldLabel.Visible = 'off';
                app.symmetricCheckBox.Visible = 'off';
                app.autocorrelatedCheckBox.Visible = 'off';
                
                if isfield(app.data,'matrices') && isfield(app.data.matrices,'loaded') && ~isempty(app.data.matrices.loaded)
                    app.NumberofmatricesEditField.Enable = 'off';
                    app.NumberofmatricesEditField.Value = app.info.matrices.loaded.Nmatrices;
                    
                    app.NumberoftimepointsEditField.Value = 1000;
                    
                    app.NumberofregionsEditField.Enable = 'off';
                    app.NumberofregionsEditField.Value = size(app.data.matrices.loaded,1);
                    if app.info.matrices.loaded.Nmatrices == 1
                        app.NumberofrealisationsEditField.Enable = 'on';
                        app.NumberofrealisationsEditField.Value = 60;
                    else
                        app.NumberofrealisationsEditField.Enable = 'off';
                        app.NumberofrealisationsEditField.Value = app.info.matrices.loaded.Nmatrices;
                    end    
                else
                    app.NumberofmatricesEditField.Enable = 'on';
                    app.NumberofmatricesEditField.Value = 1;
                    app.NumberoftimepointsEditField.Enable = 'on';
                    app.NumberoftimepointsEditField.Value = 1;
                    app.NumberofregionsEditField.Enable = 'on';
                    app.NumberofregionsEditField.Value = 1;
                    app.NumberofrealisationsEditField.Enable = 'on';                           
                    app.NumberofrealisationsEditField.Value = 1;                           
                end
               
            elseif app.DatasourceDropDown.Value == "generate data from scratch"
                app.ImportdataButton.Visible = 'off';
                app.ImportdataButton.Enable = 'off';                                
                app.ImportmatrixmatricesButton.Visible = 'off';                
                app.ImportmatrixmatricesButton.Enable = 'off';                
                app.GridLayout11.Visible = 'on';

                app.GraphdensityEditField.Visible = 'on';
                app.GraphdensityEditFieldLabel.Visible = 'on';
                app.LargesteigenvalueEditField.Visible = 'on';
                app.LargesteigenvalueEditFieldLabel.Visible = 'on';
                app.symmetricCheckBox.Visible = 'on';
                app.autocorrelatedCheckBox.Visible = 'on';
                
                app.NumberofmatricesEditField.Enable = 'on';
                app.NumberofmatricesEditField.Value = 1;
                app.NumberoftimepointsEditField.Enable = 'on';
                app.NumberoftimepointsEditField.Value = 1000;
                app.NumberofregionsEditField.Enable = 'on';
                app.NumberofregionsEditField.Value = 30;
                app.NumberofrealisationsEditField.Enable = 'on';                
                app.NumberofrealisationsEditField.Value = 60;                
                
            end
            

        end

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
         
            %%% Import data

            [filename, pathname] = uigetfile({'*.mat'}, 'Select file');
           
            %%% Update data struct
            
            app.data.loaded = importdata([pathname filename]);
            app.data.current = app.data.loaded;
           
            %%% Pre-fill data and visualisation subset input fields based on data dimensions
            
            % Data labels
            app.DatasetlabelEditField.Value = '';
            app.Group1LabelEditField.Value = 'group 1';
            app.CountEditField_Group1.Value = 0;
            app.Group2LabelEditField.Value = 'group 2';
            app.CountEditField_Group2.Value = 0;
             
            % Data subset
            app.TimepointsEditField_Subset.Value = sprintf('1:%d',size(app.data.current,1));
            app.RegionsEditField_Subset.Value = sprintf('1:%d',size(app.data.current,2));
            app.RealisationsEditField_Subset.Value = sprintf('1:%d',size(app.data.current,3));

%             % Preprocessing visualisation
%             if app.MethodDropDownVis_Preproc.Value == "individual"
%                 app.TimepointsEditField_Preproc.Value = sprintf('1:%d',size(app.data.current,1));
%                 app.RegionsEditField_Preproc.Value = '1';
%                 app.RealisationsEditField_Preproc.Value = '1';
%             elseif app.MethodDropDownVis_Preproc.Value == "summarised"
%                 app.TimepointsEditField_Preproc.Value = sprintf('1:%d',size(app.data.current,1));
%                 app.RegionsEditField_Preproc.Value = sprintf('1:%d',size(app.data.current,2));
%                 if size(app.data.current,3) >= 10
%                     app.RealisationsEditField_Preproc.Value = '1:10';
%                 else
%                     app.RealisationsEditField_Preproc.Value = sprintf('1:%d',size(app.data.current,3));
%                 end
%             end
                        
            % Dimension reduction visualisation
            if size(app.data.current,1) <= 500
                app.TimepointsEditField_DimRed.Value = sprintf('1:%d',size(app.data.current,1));
            else
                app.TimepointsEditField_DimRed.Value = '1:500';                
            end
                        
            if app.NumberofcomponentsEditField_DimRed.Value <= 10
                app.ComponentsEditField_DimRed.Value = sprintf('1:%d',app.NumberofcomponentsEditField_DimRed.Value);
            else
                app.ComponentsEditField_DimRed.Value = '1:10';                
            end

            if size(app.data.current,3) <= 5
                app.RealisationsEditField_DimRed.Value = sprintf('1:%d',size(app.data.current,3));
            else
                app.RealisationsEditField_DimRed.Value = '1:5';                
            end
            
            % Connectivity matrices visualisation                        
            if app.OptionDropDown_Matrices.Value == "first matrix"
                app.MatricescountEditField_Matrices.Value = 1;
            elseif app.OptionDropDown_Matrices.Value == "number of matrices"
                if size(app.data.current,3) >= 10
                    app.MatricescountEditField_Matrices.Value = 10;
                else
                    app.MatricescountEditField_Matrices.Value = size(app.data.current,3);
                end
            elseif app.OptionDropDown_Matrices.Value == "selected matrices"
                if size(app.data.current,3) >= 10
                    app.MatricesEditField_Matrices.Value = regexprep(num2str(1:10),'\s+',',');
                else
                    app.MatricesEditField_Matrices.Value = regexprep(num2str(1:1:(size(app.data.current,3))),'\s+',',');
                end
            end
                
            %%% Update structure with global information: data

            num_groups = 2;
            app.info.data.Ngroup = num_groups;  

            groupIndicator_temp{1} = zeros(app.CountEditField_Group1.Value,app.info.data.Ngroup);
            groupIndicator_temp{1}(:,1) = ones(app.CountEditField_Group1.Value,1); 
            groupIndicator_temp{2} = zeros(app.CountEditField_Group2.Value,app.info.data.Ngroup);
            groupIndicator_temp{2}(:,2) = ones(app.CountEditField_Group2.Value,1);
            
            app.info.data.dataset = app.DatasetlabelEditField.Value;
            app.info.data.groupNames{1,1} = app.Group1LabelEditField.Value;
            app.info.data.groupNames{2,1} = app.Group2LabelEditField.Value;
            app.info.data.groupIndicator = logical(cat(1,groupIndicator_temp{:}));
            app.info.data.NsbjPerGroup = sum(app.info.data.groupIndicator);
            app.info.data.Nsbj = sum(app.info.data.groupIndicator(:));
            
            %%% Display message
            
            app.TextArea.FontColor = '0.47,0.67,0.19';
            app.TextArea.Value = 'Data import: done';
            
        end

        % Button pushed function: ImportmatrixmatricesButton
        function ImportmatrixmatricesButtonPushed(app, event)
            
            %%% Import matrix/matrices

            app.data.matrices.loaded = [];
            [filename, pathname] = uigetfile({'*.mat'}, 'Select file');

            %%% Update data struct

            app.data.matrices.loaded = importdata([pathname filename]);
            
           %%% Update structure with global information: matrices
            
            app.info.matrices.loaded.Nmatrices = size(app.data.matrices.loaded,3);            

            %%% Pre-fill data generation input fields based on loaded
            %%% matrix/matrices
            
            app.NumberofmatricesEditField.Enable = 'off';
            app.NumberofmatricesEditField.Value = app.info.matrices.loaded.Nmatrices;
            
            app.NumberoftimepointsEditField.Value = 1000;
            
            app.NumberofregionsEditField.Enable = 'off';
            app.NumberofregionsEditField.Value = size(app.data.matrices.loaded,1);
            if app.info.matrices.loaded.Nmatrices == 1
                app.NumberofrealisationsEditField.Enable = 'on';
                app.NumberofrealisationsEditField.Value = 60;
            else
                app.NumberofrealisationsEditField.Enable = 'off';
                app.NumberofrealisationsEditField.Value = app.info.matrices.loaded.Nmatrices;
            end

            %%% Display message
            
            app.TextArea.FontColor = '0.47,0.67,0.19';
            if app.info.matrices.loaded.Nmatrices == 1
                app.TextArea.Value = 'Matrix import: done';
            else
                app.TextArea.Value = 'Matrices import: done';  
            end
            
        end

        % Value changed function: NumberofmatricesEditField
        function NumberofmatricesEditFieldValueChanged(app, event)
            
            if app.DatasourceDropDown.Value == "generate data from scratch"
                if app.NumberofmatricesEditField.Value == 1
                    app.NumberofrealisationsEditField.Enable = 'on';                    
                elseif app.NumberofmatricesEditField.Value > 1
                    app.NumberofrealisationsEditField.Value = app.NumberofmatricesEditField.Value;
                    app.NumberofrealisationsEditField.Enable = 'off';
                end
            end
            
        end

        % Button pushed function: GeneratedataButton
        function GeneratedataButtonPushed(app, event)

            %%% Update structure with global information: matrices
            
            if app.symmetricCheckBox.Value == 1
                app.info.matrices.symmetry = 'sym';
            elseif app.symmetricCheckBox.Value == 0
                app.info.matrices.symmetry = 'not_sym';                
            end
            
            if app.autocorrelatedCheckBox.Value == 1
                app.info.matrices.autocorrelation = 'autocorr';
            elseif app.autocorrelatedCheckBox.Value == 0
                app.info.matrices.autocorrelation = 'not_autocorr';                
            end
            
            %%% Update data struct
            
            % generating data from imported matrix/matrices
            if app.DatasourceDropDown.Value == "import matrix/matrices and generate data"
                if app.info.matrices.loaded.Nmatrices == 1
                    app.data.generated = cocoa_matrix_to_data_gen(app.data.matrices.loaded,app.NumberoftimepointsEditField.Value,app.NumberofrealisationsEditField.Value);
                else
                    app.data.generated = cocoa_matrices_to_data_gen(app.data.matrices.loaded,app.NumberoftimepointsEditField.Value);
                end    
            % generating data from scratch (no imported matrix/matrices)
            elseif app.DatasourceDropDown.Value == "generate data from scratch"
                
                app.info.matrices.generated.Nmatrices = app.NumberofmatricesEditField.Value;
                app.data.matrices.generated = cocoa_ER_matrix_set_VAR1(app.info.matrices.generated.Nmatrices,app.NumberofregionsEditField.Value,app.GraphdensityEditField.Value,app.LargesteigenvalueEditField.Value,app.info.matrices.symmetry,app.info.matrices.autocorrelation);

                if app.info.matrices.generated.Nmatrices == 1
                    app.data.generated = cocoa_matrix_to_data_gen(app.data.matrices.generated,app.NumberoftimepointsEditField.Value,app.NumberofrealisationsEditField.Value);
                else
                    app.data.generated = cocoa_matrices_to_data_gen(app.data.matrices.generated,app.NumberoftimepointsEditField.Value);
                end               
                
            end
            
            app.data.current = app.data.generated;
            
            %%% Pre-fill data and visualisation subset input fields based on data dimensions
            
            % Data labels
            app.DatasetlabelEditField.Value = '';
            app.Group1LabelEditField.Value = 'group 1';
            app.CountEditField_Group1.Value = 0;
            app.Group2LabelEditField.Value = 'group 2';
            app.CountEditField_Group2.Value = 0;

            % Data subset
            app.TimepointsEditField_Subset.Value = sprintf('1:%d',size(app.data.current,1));
            app.RegionsEditField_Subset.Value = sprintf('1:%d',size(app.data.current,2));
            app.RealisationsEditField_Subset.Value = sprintf('1:%d',size(app.data.current,3));

%             % Preprocessing visualisation
%             if app.MethodDropDownVis_Preproc.Value == "individual"
%                 app.TimepointsEditField_Preproc.Value = sprintf('1:%d',size(app.data.current,1));
%                 app.RegionsEditField_Preproc.Value = '1';
%                 app.RealisationsEditField_Preproc.Value = '1';
%             elseif app.MethodDropDownVis_Preproc.Value == "summarised"
%                 app.TimepointsEditField_Preproc.Value = sprintf('1:%d',size(app.data.current,1));
%                 app.RegionsEditField_Preproc.Value = sprintf('1:%d',size(app.data.current,2));
%                 if size(app.data.current,3) >= 10
%                     app.RealisationsEditField_Preproc.Value = '1:10';
%                 else
%                     app.RealisationsEditField_Preproc.Value = sprintf('1:%d',size(app.data.current,3));
%                 end
%             end
                        
            % Dimension reduction visualisation
            if size(app.data.current,1) <= 500
                app.TimepointsEditField_DimRed.Value = sprintf('1:%d',size(app.data.current,1));
            else
                app.TimepointsEditField_DimRed.Value = '1:500';                
            end
                        
            if app.NumberofcomponentsEditField_DimRed.Value <= 10
                app.ComponentsEditField_DimRed.Value = sprintf('1:%d',app.NumberofcomponentsEditField_DimRed.Value);
            else
                app.ComponentsEditField_DimRed.Value = '1:10';                
            end

            if size(app.data.current,3) <= 5
                app.RealisationsEditField_DimRed.Value = sprintf('1:%d',size(app.data.current,3));
            else
                app.RealisationsEditField_DimRed.Value = '1:5';                
            end
            
            % Connectivity matrices visualisation                        
            if app.OptionDropDown_Matrices.Value == "first matrix"
                app.MatricescountEditField_Matrices.Value = 1;
            elseif app.OptionDropDown_Matrices.Value == "number of matrices"
                if size(app.data.current,3) >= 10
                    app.MatricescountEditField_Matrices.Value = 10;
                else
                    app.MatricescountEditField_Matrices.Value = size(app.data.current,3);
                end
            elseif app.OptionDropDown_Matrices.Value == "selected matrices"
                if size(app.data.current,3) >= 10
                    app.MatricesEditField_Matrices.Value = regexprep(num2str(1:10),'\s+',',');
                else
                    app.MatricesEditField_Matrices.Value = regexprep(num2str(1:1:(size(app.data.current,3))),'\s+',',');
                end
            end
                
            %%% Update structure with global information: data

            num_groups = 2;
            app.info.data.Ngroup = num_groups;  

            groupIndicator_temp{1} = zeros(app.CountEditField_Group1.Value,app.info.data.Ngroup);
            groupIndicator_temp{1}(:,1) = ones(app.CountEditField_Group1.Value,1); 
            groupIndicator_temp{2} = zeros(app.CountEditField_Group2.Value,app.info.data.Ngroup);
            groupIndicator_temp{2}(:,2) = ones(app.CountEditField_Group2.Value,1);
            
            app.info.data.dataset = app.DatasetlabelEditField.Value;
            app.info.data.groupNames{1,1} = app.Group1LabelEditField.Value;
            app.info.data.groupNames{2,1} = app.Group2LabelEditField.Value;
            app.info.data.groupIndicator = logical(cat(1,groupIndicator_temp{:}));
            app.info.data.NsbjPerGroup = sum(app.info.data.groupIndicator);
            app.info.data.Nsbj = sum(app.info.data.groupIndicator(:));
                        
            %%% Display message
            
            app.TextArea.FontColor = '0.47,0.67,0.19';
            app.TextArea.Value = 'Data generation: done';            
            
        end

        % Button pushed function: LabeldataButton
        function LabeldataButtonPushed(app, event)
            
            %%% Update structure with global information: data

            groupIndicator_temp{1} = zeros(app.CountEditField_Group1.Value,app.info.data.Ngroup);
            groupIndicator_temp{1}(:,1) = ones(app.CountEditField_Group1.Value,1); 
            groupIndicator_temp{2} = zeros(app.CountEditField_Group2.Value,app.info.data.Ngroup);
            groupIndicator_temp{2}(:,2) = ones(app.CountEditField_Group2.Value,1);
                   
            app.info.data.dataset = app.DatasetlabelEditField.Value;
            app.info.data.groupNames{1,1} = app.Group1LabelEditField.Value;
            app.info.data.groupNames{2,1} = app.Group2LabelEditField.Value;
            app.info.data.groupIndicator = logical(cat(1,groupIndicator_temp{:}));
            app.info.data.NsbjPerGroup = sum(app.info.data.groupIndicator);
            app.info.data.Nsbj = sum(app.info.data.groupIndicator(:));
            
            %%% Display message
            
            num_subs = size(app.info.data.groupIndicator,1);

            if num_subs ~= size(app.data.current,3)  
                app.TextArea.FontColor = '1,0,0';
                app.TextArea.Value = 'Wrong number of realisations!';
                % error('Wrong number of realisations!')
            elseif any(app.info.data.NsbjPerGroup==0)
                app.TextArea.FontColor = '#ff8800';   
                group = find(app.info.data.NsbjPerGroup==0);
                app.TextArea.Value = sprintf('Data labeling: done. WARNING: Zero subjects in group %d.', group); 
            else
                app.TextArea.FontColor = '0.47,0.67,0.19';
                app.TextArea.Value = 'Data labeling: done';
            end
            
        end

        % Button pushed function: SelectsubsetButton_Subset
        function SelectsubsetButton_SubsetPushed(app, event)
     
            %%% Update structure with global information: data
            
            app.info.data.subsetDim1 = cocoa_logicalVector(app.TimepointsEditField_Subset.Value,size(app.data.current,1), app.TimepointsSwitch_Subset.Value);
            app.info.data.subsetDim2 = cocoa_logicalVector(app.RegionsEditField_Subset.Value,size(app.data.current,2), app.RegionsSwitch_Subset.Value);
            app.info.data.subsetDim3 = cocoa_logicalVector(app.RealisationsEditField_Subset.Value,size(app.data.current,3), app.RealisationsSwitch_Subset.Value);
            
            num_groups = 2;

            groupIndicator_temp{1} = zeros(app.info.data.NsbjPerGroup(1),num_groups);
            groupIndicator_temp{1}(:,1) = app.info.data.subsetDim3(1:app.info.data.NsbjPerGroup(1));
            groupIndicator_temp{2} = zeros(app.info.data.NsbjPerGroup(2),num_groups);
            groupIndicator_temp{2}(:,2) = app.info.data.subsetDim3((app.info.data.NsbjPerGroup(1)+1):(app.info.data.NsbjPerGroup(1)+app.info.data.NsbjPerGroup(2)));

            app.info.data.groupIndicator = logical(cat(1,groupIndicator_temp{:}));
            app.info.data.groupIndicator = app.info.data.groupIndicator(any(app.info.data.groupIndicator,2),:);
            
            app.info.data.NsbjPerGroup = sum(app.info.data.groupIndicator);
            app.info.data.Nsbj = sum(app.info.data.groupIndicator(:));
            app.info.data.Ngroup = nnz(app.info.data.NsbjPerGroup);  
                        
            %%% Update data struct
            
            app.data.subsetted = app.data.current((app.info.data.subsetDim1),(app.info.data.subsetDim2),(app.info.data.subsetDim3));
            app.data.current = app.data.subsetted;

            %%% Update pre-filled visualisation subset input fields
            
%             % Preprocessing visualisation
%             if app.MethodDropDownVis_Preproc.Value == "summarised"
%                 if size(app.data.current,3) >= 10
%                     app.RealisationsEditField_Preproc.Value = '1:10';
%                 else
%                     app.RealisationsEditField_Preproc.Value = sprintf('1:%d',size(app.data.current,3));
%                 end
%             end
                        
            % Dimension reduction visualisation
            if size(app.data.current,1) <= 500
                app.TimepointsEditField_DimRed.Value = sprintf('1:%d',size(app.data.current,1));
            else
                app.TimepointsEditField_DimRed.Value = '1:500';                
            end

            if app.NumberofcomponentsEditField_DimRed.Value <= 10
                app.ComponentsEditField_DimRed.Value = sprintf('1:%d',app.NumberofcomponentsEditField_DimRed.Value);
            else
                app.ComponentsEditField_DimRed.Value = '1:10';                
            end
                        
            if size(app.data.current,3) <= 5
                app.RealisationsEditField_DimRed.Value = sprintf('1:%d',size(app.data.current,3));
            else
                app.RealisationsEditField_DimRed.Value = '1:5';                
            end
            
            % Connectivity matrices visualisation                        
            if app.OptionDropDown_Matrices.Value == "number of matrices"
                if size(app.data.current,3) >= 10
                    app.MatricescountEditField_Matrices.Value = 10;
                else
                    app.MatricescountEditField_Matrices.Value = size(app.data.current,3);
                end
            elseif app.OptionDropDown_Matrices.Value == "selected matrices"
                if size(app.data.current,3) >= 10
                    app.MatricesEditField_Matrices.Value = regexprep(num2str(1:10),'\s+',',');
                else
                    app.MatricesEditField_Matrices.Value = regexprep(num2str(1:1:(size(app.data.current,3))),'\s+',',');
                end
            end            
            
            %%% Display message

            if any(app.info.data.NsbjPerGroup==0)
                app.TextArea.FontColor = '#ff8800';   
                group = find(app.info.data.NsbjPerGroup==0);
                app.TextArea.Value = sprintf('Subset selection: done. Subset dimensions: %d timepoints * %d regions * %d realisations. WARNING: Zero subjects in group %d.', size(app.data.current,1), size(app.data.current,2), size(app.data.current,3), group); 
            else
                app.TextArea.FontColor = '0.47,0.67,0.19';
                displayMessage = sprintf('Subset selection: done. Subset dimensions: %d timepoints * %d regions * %d realisations.', size(app.data.current,1), size(app.data.current,2), size(app.data.current,3));
                app.TextArea.Value = displayMessage;
            end
            
        end

        % Button pushed function: ResetButton_Subset
        function ResetButton_SubsetPushed(app, event)
         
            %%% Update data struct
           
            app.data.current = app.data.loaded;
            
            %%% Update prefilled data
            
            % Subset input fields
            app.TimepointsEditField_Subset.Value = sprintf('1:%d',size(app.data.current,1));
            app.RegionsEditField_Subset.Value = sprintf('1:%d',size(app.data.current,2));
            app.RealisationsEditField_Subset.Value = sprintf('1:%d',size(app.data.current,3));

            % Dimension reduction visualisation
            if size(app.data.current,1) <= 500
                app.TimepointsEditField_DimRed.Value = sprintf('1:%d',size(app.data.current,1));
            else
                app.TimepointsEditField_DimRed.Value = '1:500';                
            end
                        
            if app.NumberofcomponentsEditField_DimRed.Value <= 10
                app.ComponentsEditField_DimRed.Value = sprintf('1:%d',app.NumberofcomponentsEditField_DimRed.Value);
            else
                app.ComponentsEditField_DimRed.Value = '1:10';                
            end

            if size(app.data.current,3) <= 5
                app.RealisationsEditField_DimRed.Value = sprintf('1:%d',size(app.data.current,3));
            else
                app.RealisationsEditField_DimRed.Value = '1:5';                
            end
            
            % Connectivity matrices visualisation                        
            if app.OptionDropDown_Matrices.Value == "first matrix"
                app.MatricescountEditField_Matrices.Value = 1;
            elseif app.OptionDropDown_Matrices.Value == "number of matrices"
                if size(app.data.current,3) >= 10
                    app.MatricescountEditField_Matrices.Value = 10;
                else
                    app.MatricescountEditField_Matrices.Value = size(app.data.current,3);
                end
            elseif app.OptionDropDown_Matrices.Value == "selected matrices"
                if size(app.data.current,3) >= 10
                    app.MatricesEditField_Matrices.Value = regexprep(num2str(1:10),'\s+',',');
                else
                    app.MatricesEditField_Matrices.Value = regexprep(num2str(1:1:(size(app.data.current,3))),'\s+',',');
                end
            end
            
            %%% Update structure with global information: data
            
            num_groups = 2;
            app.info.data.Ngroup = num_groups;  

            groupIndicator_temp{1} = zeros(app.CountEditField_Group1.Value,app.info.data.Ngroup);
            groupIndicator_temp{1}(:,1) = ones(app.CountEditField_Group1.Value,1); 
            groupIndicator_temp{2} = zeros(app.CountEditField_Group2.Value,app.info.data.Ngroup);
            groupIndicator_temp{2}(:,2) = ones(app.CountEditField_Group2.Value,1);
            
            app.info.data.dataset = app.DatasetlabelEditField.Value;
            app.info.data.groupNames{1,1} = app.Group1LabelEditField.Value;
            app.info.data.groupNames{2,1} = app.Group2LabelEditField.Value;
            app.info.data.groupIndicator = logical(cat(1,groupIndicator_temp{:}));
            app.info.data.NsbjPerGroup = sum(app.info.data.groupIndicator);
            app.info.data.Nsbj = sum(app.info.data.groupIndicator(:));
            
            %%% Display message
            
            app.TextArea.FontColor = '0.47,0.67,0.19';
            displayMessage = sprintf('Data dimensions: %d timepoints * %d regions * %d realisations.', size(app.data.current));
            app.TextArea.Value = displayMessage;            
            
        end

        % Value changed function: MethodDropDown_DimRed
        function MethodDropDown_DimRedValueChanged(app, event)
            value = app.MethodDropDown_DimRed.Value;
            
            %%% Set default parameter labels based on selected
            %%% dimensionality reduction method
            
            if value == "k-means"
                app.NumberofcomponentsEditFieldLabel.Text = "Number of clusters:";
                app.ComponentsEditFieldLabel.Text = "Clusters:";
                app.DimensionDropDown_DimRed.Items = {'temporal'};
            else
                app.NumberofcomponentsEditFieldLabel.Text = "Number of components:";                
                app.ComponentsEditFieldLabel.Text = "Components:";
                app.DimensionDropDown_DimRed.Items = {'temporal', 'spatial'};
                if size(app.data.current,1)*size(app.data.current,3) >= size(app.data.current,2)
                    app.DimensionDropDown_DimRed.Value = 'temporal';
                else
                    app.DimensionDropDown_DimRed.Value = 'spatial';
                end
            end
            
        end

        % Value changed function: NumberofcomponentsEditField_DimRed
        function NumberofcomponentsEditField_DimRedValueChanged(app, event)
            
            if ~isempty(app.ComponentsEditField_DimRed.Value)
                if app.NumberofcomponentsEditField_DimRed.Value <= 10
                    app.ComponentsEditField_DimRed.Value = sprintf('1:%d',app.NumberofcomponentsEditField_DimRed.Value);
                else
                    app.ComponentsEditField_DimRed.Value = '1:10';                
                end 
            end
            
        end

        % Button pushed function: ReducedimensionalityButton_DimRed
        function ReducedimensionalityButton_DimRedPushed(app, event)
           
            %%% Create app.info.preprocessing steps if it does not exist
            if ~isfield(app.info,'preprocessing')
                app.info.preprocessing.steps = {};
            end
            
            %%% Warn user if normalisation has not been performed
            
            if isfield(app.info,'preprocessing')
                 if ~any(strcmp(app.info.preprocessing.steps,'normalize'))
                    warning('Normalisation preprocessing step not performed within this toolbox. Please consider normalisation of the data according to the selected dimension reduction method.')
                end
            else
                warning('Normalisation preprocessing step not performed within this toolbox. Please consider normalisation of the data according to the selected dimension reduction method.')
            end
            
            %%% Map GUI labels and function inputs
            
            dimReductionMethodMap = containers.Map({'PCA', 'ICA', 'NNMF', 'k-means'}, {'pca', 'fastica', 'nnmf', 'kmeans'});
            method = dimReductionMethodMap(app.MethodDropDown_DimRed.Value);

            dimReductionDimensionMap = containers.Map({'temporal', 'spatial'}, {'col', 'row'});
            dim = dimReductionDimensionMap(app.DimensionDropDown_DimRed.Value);
            
            %%% Dimensionality reduction
            
            [components,loadings] = cocoa_dimension_reduction(app.data.current,'method',method,'nComp',app.NumberofcomponentsEditField_DimRed.Value,'dim',dim);

            %%% Update structure with global information: dimReduction
            
            app.data.dimReduction.components = components;
            app.data.dimReduction.loadings = loadings;
            
            %%% Update data struct
            
            app.data.dimReduction.inputData = app.data.current;            
            app.data.current = app.data.dimReduction.components;
            
            %%% Update pre-filled visualisation subset input fields

            if size(app.data.current,1) <= 500
                app.TimepointsEditField_DimRed.Value = sprintf('1:%d',size(app.data.current,1));
            else
                app.TimepointsEditField_DimRed.Value = '1:500';                
            end
            
            if app.NumberofcomponentsEditField_DimRed.Value <= 10
                app.ComponentsEditField_DimRed.Value = sprintf('1:%d',app.NumberofcomponentsEditField_DimRed.Value);
            else
                app.ComponentsEditField_DimRed.Value = '1:10';                
            end

            if size(app.data.current,3) <= 5
                app.RealisationsEditField_DimRed.Value = sprintf('1:%d',size(app.data.current,3));
            else
                app.RealisationsEditField_DimRed.Value = '1:5';                
            end

            %%% Display message
            
            app.TextArea.FontColor = '0.47,0.67,0.19';            
            app.TextArea.Value = 'Dimension reduction: done';
            
        end

        % Button pushed function: ResetButton_DimRed
        function ResetButton_DimRedPushed(app, event)

            %%% Update structure with global information: dimReduction
            
            if isfield(app.data, 'dimReduction') && isfield(app.data.dimReduction,'components')
                app.data.dimReduction = rmfield(app.data.dimReduction, 'components');
            end
            
            if isfield(app.data, 'dimReduction') && isfield(app.data.dimReduction,'loadings')            
                app.data.dimReduction = rmfield(app.data.dimReduction, 'loadings');
            end
            
            %%% Update data struct
            
            if isfield(app.data, 'dimReduction')
                app.data.current = app.data.dimReduction.inputData; 
            end
            
            %%% Set default parameter labels and values
            
            app.MethodDropDown_DimRed.Value = "PCA";
            app.NumberofcomponentsEditField_DimRed.Value = 1;
            app.DimensionDropDown_DimRed.Value = "temporal";
                        
            if app.MethodDropDown_DimRed.Value == "k-means"
                app.NumberofcomponentsEditFieldLabel.Text = "Number of clusters:";
                app.ComponentsEditFieldLabel.Text = "Clusters:";
                app.DimensionDropDown_DimRed.Items = {'temporal'};
            else
                app.NumberofcomponentsEditFieldLabel.Text = "Number of components:";                
                app.ComponentsEditFieldLabel.Text = "Components:";
                app.DimensionDropDown_DimRed.Items = {'temporal', 'spatial'};
                if size(app.data.current,1)*size(app.data.current,3) >= size(app.data.current,2)
                    app.DimensionDropDown_DimRed.Value = 'temporal';
                else
                    app.DimensionDropDown_DimRed.Value = 'spatial';
                end
            end
            
            %%% Display message

            app.TextArea.FontColor = '0.47,0.67,0.19';            
            app.TextArea.Value = 'Back to original data (prior to dimensionality reduction).';
            
        end

        % Button pushed function: VisualiseButton_DimRed
        function VisualiseButton_DimRedPushed(app, event)
                     
            %%% Set figure title prefix
            
            figPrefix = '[Dimension reduction] ';
            
            %%% Update structure with global information: dimReduction
            
            app.info.dimReduction.subsetDim1 = cocoa_logicalVector(app.TimepointsEditField_DimRed.Value,size(app.data.current,1), app.TimepointsSwitch_DimRed.Value);
            app.info.dimReduction.subsetDim2 = cocoa_logicalVector(app.ComponentsEditField_DimRed.Value,size(app.data.current,2), app.RegionsSwitch_DimRed.Value);
            app.info.dimReduction.subsetDim3 = cocoa_logicalVector(app.RealisationsEditField_DimRed.Value,size(app.data.current,3), app.RealisationsSwitch_DimRed.Value);

            %%% Visualise data with reduced dimensionality

            cocoa_visualise_data(app.data.current,'method',app.MethodDropDownVis_DimRed.Value,'subsetDim1',app.info.dimReduction.subsetDim1,...
                'subsetDim2',app.info.dimReduction.subsetDim2, 'subsetDim3',app.info.dimReduction.subsetDim3,'figPrefix',figPrefix);
            
        end

        % Button pushed function: ComputeButton_Matrices
        function ComputeButton_MatricesPushed(app, event)

            %%% Display message
          
            app.TextArea.FontColor = '0,0,0';            
            app.TextArea.Value = 'Computing connectivity matrices...';
            
            %%% Map GUI labels and function inputs

            connectivityMeasureMap = containers.Map({'Pearson correlation', 'partial Pearson correlation', 'Spearman correlation', 'mutual information', 'Granger causality'}, {'cor_pearson', 'part_cor', 'cor_spearman', 'MI_knn', 'GC'});
            conn_measure = connectivityMeasureMap(app.ConnectivitymeasureDropDown_Matrices.Value);

            %%% Connectivity matrices

            [connMatrix, pval_mat, other] = cocoa_connectivity_compute(app.data.current, conn_measure);

            %%% Update data.connectivity struct

            app.data.connectivity.connMatrix = connMatrix;
            app.data.connectivity.pvalue = pval_mat;
            
            %%% Update structure with global information: connectivity
            
            app.info.connectivity.measure = conn_measure;
            app.info.connectivity.symmetry = other.sym;
            app.info.connectivity.matrixSize = size(connMatrix,1);

            %%% Set default interactivity of Graph theory analysis tab
            %%% based on connectivity matrix dimensions and symmetricity
            
            if app.info.connectivity.symmetry == 0 || app.info.connectivity.matrixSize == 1
                app.ThresholddensityEditFieldLabel.Enable = 'off';
                app.ThresholddensityEditField_Graphs.Enable = 'off';
                app.alphathresholdEditField_GraphsLabel.Enable = 'off';
                app.alphathresholdEditField_Graphs.Enable = 'off';
                app.ComputeButton_Graphs.Enable = 'off';
                app.VisualiseButton_Graphs.Enable = 'off';
            else
                app.ThresholddensityEditFieldLabel.Enable = 'on';
                app.ThresholddensityEditField_Graphs.Enable = 'on';
                app.alphathresholdEditField_GraphsLabel.Enable = 'on';
                app.alphathresholdEditField_Graphs.Enable = 'on';
                app.ComputeButton_Graphs.Enable = 'on';
                app.VisualiseButton_Graphs.Enable = 'on';                
            end
            
            %%% Display message            
            
            app.TextArea.FontColor = '0.47,0.67,0.19';            
            app.TextArea.Value = 'Connectivity computation: done';
            
        end

        % Value changed function: OptionDropDown_Matrices
        function OptionDropDown_MatricesValueChanged(app, event)
            value = app.OptionDropDown_Matrices.Value;
            
            %%% Set default interactivity of matrices visualisation based
            %%% on selected visualisation method
            
            if value == "first matrix"
                app.MatricescountEditFieldLabel.Visible = 'on';
                app.MatricescountEditField_Matrices.Visible = 'on';
                app.MatricescountEditField_Matrices.Enable = 'off';
                app.MatricesEditFieldLabel.Visible = 'off';
                app.MatricesEditField_Matrices.Visible = 'off';
                app.MatricesEditField_Matrices.Enable = 'off';
                app.MatricescountEditField_Matrices.Value = 1;
            elseif value == "number of matrices"
                app.MatricescountEditFieldLabel.Visible = 'on';
                app.MatricescountEditField_Matrices.Visible = 'on';
                app.MatricescountEditField_Matrices.Enable = 'on';
                app.MatricesEditFieldLabel.Visible = 'off';
                app.MatricesEditField_Matrices.Visible = 'off';
                app.MatricesEditField_Matrices.Enable = 'off';
                if size(app.data.current,3) >= 10
                    app.MatricescountEditField_Matrices.Value = 10;
                else
                    app.MatricescountEditField_Matrices.Value = size(app.data.current,3);
                end
            elseif value == "selected matrices"
                app.MatricescountEditFieldLabel.Visible = 'off';
                app.MatricescountEditField_Matrices.Visible = 'off';
                app.MatricescountEditField_Matrices.Enable = 'off';
                app.MatricesEditFieldLabel.Visible = 'on';
                app.MatricesEditField_Matrices.Visible = 'on';
                app.MatricesEditField_Matrices.Enable = 'on';
                if size(app.data.current,3) >= 10
                    app.MatricesEditField_Matrices.Value = regexprep(num2str(1:10),'\s+',',');
                else
                    app.MatricesEditField_Matrices.Value = regexprep(num2str(1:1:(size(app.data.current,3))),'\s+',',');
                end
            end
            
        end

        % Button pushed function: VisualiseButton_Matrices
        function VisualiseButton_MatricesPushed(app, event)

            %%% Parse user input
                        
            if app.OptionDropDown_Matrices.Value == "selected matrices"
                
                userInput = app.MatricesEditField_Matrices.Value;
                userInput = erase(userInput,{'[',']'});
                userInput = split(userInput, {',',' ',';','.'});
                dataLength = size(app.data.current,3);

                indices = [];
                for nCell = 1:length(userInput)
                    cellNumeric = str2num(userInput{nCell});
                    indices = [indices cellNumeric];
                end
                                
                indices = unique(sort(indices));
                
                if length(indices) > dataLength
                    indices = indices(1:dataLength);
                end
                
                % Visualise connectivity matrices
                cocoa_connectivity_visual(app.data.connectivity.connMatrix,app.info,'selectMat',indices);
            else
                cocoa_connectivity_visual(app.data.connectivity.connMatrix,app.info,'Nmat',app.MatricescountEditField_Matrices.Value);
            end

        end

        % Value changed function: MethodDropDown_Stats
        function MethodDropDown_StatsValueChanged(app, event)
            value = app.MethodDropDown_Stats.Value;

            %%% Set default interactivity of Statistical inference 
            %%% parameters based on selected method
           
            if value == "p-value masking"
                app.GroupcomparisontestDropDown_Stats.Enable = 'on';
                app.MultiplecorrectionmethodDropDown_Stats.Enable = 'on';
                app.alphathresholdEditField_Stats.Enable = 'on';
                app.FeatureDropDownLabel_Stats.Enable = 'off';                
                app.FeatureDropDown_Stats.Enable = 'off';
                app.AdditionalmatrixLabel_Stats.Enable = 'off';
                app.LoadmatrixButton_Stats.Enable = 'off';
                app.LoadmatrixButton_Stats.Text = 'Load matrix';
                app.FeatureDropDown_Stats.Value = 'global connectivity';
            elseif value == "ROC curve"
                app.GroupcomparisontestDropDown_Stats.Enable = 'off';
                app.MultiplecorrectionmethodDropDown_Stats.Enable = 'off';
                app.alphathresholdEditField_Stats.Enable = 'off';
                app.FeatureDropDownLabel_Stats.Enable = 'on';
                app.FeatureDropDown_Stats.Enable = 'on';
                app.AdditionalmatrixLabel_Stats.Enable = 'on'; 
                app.GroupcomparisontestDropDown_Stats.Value = 'Mann-Whitney U-test';
                app.MultiplecorrectionmethodDropDown_Stats.Value = "FWE - Hochberg's procedure";
                app.alphathresholdEditField_Stats.Value = 0.05;
                if value == "typicality of functional connectivity"
                app.LoadmatrixButton_Stats.Enable = 'on';                
                app.LoadmatrixButton_Stats.Text = 'Load gold standard';
                elseif value == "mask connectivity"
                    app.LoadmatrixButton_Stats.Enable = 'on';                
                    app.LoadmatrixButton_Stats.Text = 'Load mask';
                elseif value == "average mask connectivity"
                    app.LoadmatrixButton_Stats.Enable = 'on';                
                    app.LoadmatrixButton_Stats.Text = 'Load mask'; 
                else
                    app.LoadmatrixButton_Stats.Enable = 'off';
                    app.LoadmatrixButton_Stats.Text = 'Load matrix';                
                end                
            end
            
        end

        % Value changed function: FeatureDropDown_Stats
        function FeatureDropDown_StatsValueChanged(app, event)
            value = app.FeatureDropDown_Stats.Value;
            
            %%% Set Additional matrix button label based on selected feature
            
            if value == "typicality of functional connectivity"
                app.LoadmatrixButton_Stats.Enable = 'on';                
                app.LoadmatrixButton_Stats.Text = 'Load gold standard';
            elseif value == "mask connectivity"
                app.LoadmatrixButton_Stats.Enable = 'on';                
                app.LoadmatrixButton_Stats.Text = 'Load mask';
            elseif value == "average mask connectivity"
                app.LoadmatrixButton_Stats.Enable = 'on';                
                app.LoadmatrixButton_Stats.Text = 'Load mask'; 
            else
                app.LoadmatrixButton_Stats.Enable = 'off';
                app.LoadmatrixButton_Stats.Text = 'Load matrix';                
            end
            
        end

        % Button pushed function: LoadmatrixButton_Stats
        function LoadmatrixButton_StatsPushed(app, event)
            
            %%% Load additional matrix
             
            [filename, pathname] = uigetfile({'*.mat'}, 'Select file');
            
            %%% Update data.statInference struct
            
            app.data.statInference.additionalMatrix = importdata([pathname filename]);

            %%% Display message
            
            app.TextArea.FontColor = '0.47,0.67,0.19';                        
            app.TextArea.Value = 'Load additional matrix: done';
            
        end

        % Button pushed function: ComputeButton_Stats
        function ComputeButton_StatsPushed(app, event)
                        
            %%% Statistical inference
                
            if app.MethodDropDown_Stats.Value == "p-value masking"
               
                statAnalysis = 'p-value_mask';
                
                %%% Map GUI labels and function inputs
                
                grpCompMap = containers.Map({'Mann-Whitney U-test', 't-test'}, {'MW', 'ttest'});
                grpComp = grpCompMap(app.GroupcomparisontestDropDown_Stats.Value);
                
                multiCorrectionMap = containers.Map({char("FWE - Hochberg's procedure"), 'FWE - Bonferroni correction', 'FDR', 'none'}, {'FWE', 'Bonferroni', 'FDR', 'none'});
                multiCorrection = multiCorrectionMap(app.MultiplecorrectionmethodDropDown_Stats.Value);
                
                %%% Update structure with results: statistical inference

                app.results.statInference = cocoa_statInf_compute(statAnalysis,app.data.connectivity.connMatrix,app.info.data.groupIndicator,...
                'multiCorrection',multiCorrection,'groupComparison',grpComp,'alpha',app.alphathresholdEditField_Stats.Value,'symmetry',app.info.connectivity.symmetry);                
            
                %%% Update structure with global information: statistical inference
        
                app.info.statInf.statAnalysis = statAnalysis;
                app.info.statInf.feature = 'Conn_Matrix';
                app.info.statInf.correction = app.MultiplecorrectionmethodDropDown_Stats.Value;
                app.info.statInf.groupComp = grpComp;
                app.info.statInf.treshold = app.alphathresholdEditField_Stats.Value;
            
            elseif app.MethodDropDown_Stats.Value == "ROC curve"
                
                statAnalysis = 'ROC';

                %%% Map GUI labels and function inputs
                
                featureMap = containers.Map({'global connectivity', 'typicality of functional connectivity', 'mask connectivity', 'average mask connectivity', 'PCA'}, {'GlobConn', 'TFC', 'MaskConn', 'AvgMaskConn', 'PCA'});
                feature = featureMap(app.FeatureDropDown_Stats.Value);
                
                if feature == "GlobConn"
                    [score] = cocoa_featurization(app.data.connectivity.connMatrix,'method',feature,'symmetry',app.info.connectivity.symmetry);
                elseif feature == "TFC"
                    [score] = cocoa_featurization(app.data.connectivity.connMatrix,'method',feature,'addMatrix',app.data.statInference.additionalMatrix);
                elseif feature == "MaskConn"
                    [score] = cocoa_featurization(app.data.connectivity.connMatrix,'method',feature,'addMatrix',app.data.statInference.additionalMatrix);
                elseif feature == "AvgMaskConn"
                    [score] = cocoa_featurization(app.data.connectivity.connMatrix,'method',feature,'addMatrix',app.data.statInference.additionalMatrix);
                elseif feature == "PCA"
                    [score] = cocoa_featurization(app.data.connectivity.connMatrix,'method',feature,'symmetry',app.info.connectivity.symmetry);
                end
                            
                %%% Update structure with results: statistical inference
                
                app.results.statInference = cocoa_statInf_compute(statAnalysis,score,app.info.data.groupIndicator);

                %%% Update structure with global information: statistical inference

                app.info.statInf.statAnalysis = statAnalysis;
                app.info.statInf.feature = feature;
                if isfield(app.info.statInf,'correction')
                    app.info.statInf = rmfield(app.info.statInf, 'correction');
                end
                if isfield(app.info.statInf,'groupComp')                
                    app.info.statInf = rmfield(app.info.statInf, 'groupComp');
                end
                if isfield(app.info.statInf,'threshold')
                    app.info.statInf = rmfield(app.info.statInf, 'threshold');
                end
                            
            end
            
            %%% Display message
            if any(app.info.data.NsbjPerGroup==0)
                app.TextArea.FontColor = '#ff8800';   
                group = find(app.info.data.NsbjPerGroup==0);
                app.TextArea.Value = sprintf('Statistical Inference: done. WARNING: Zero subjects in group %d.', group); 
            else
                app.TextArea.FontColor = '0.47,0.67,0.19';                                    
                app.TextArea.Value = 'Statistical Inference: done';
            end

        end

        % Button pushed function: VisualiseButton_Stats
        function VisualiseButton_StatsPushed(app, event)
           
            %%% Visualise results of statistical analysis
            
            cocoa_statInf_visual(app.info.statInf,app.results.statInference,app.info);
            
        end

        % Button pushed function: ComputeButton_Graphs
        function ComputeButton_GraphsPushed(app, event)

            %%% Display message
          
            app.TextArea.FontColor = '0,0,0';            
            app.TextArea.Value = 'Computing...';
            
            %%% Graph theory analysis

            dens = app.ThresholddensityEditField_Graphs.Value;
            p = app.alphathresholdEditField_Graphs.Value;

            %%% Update structure with global information: graph

            app.info.graph.dens = dens;
            app.info.graph.p = p;
            
            %%% Update structure with results: graph
            
            app.results.graph = cocoa_graph_prop_undir(app.data.connectivity.connMatrix,app.info.data.groupIndicator,dens,p);
            
            %%% Display message            
            if any(app.info.data.NsbjPerGroup==0)
                app.TextArea.FontColor = '#ff8800';   
                group = find(app.info.data.NsbjPerGroup==0);
                app.TextArea.Value = sprintf('Graph theory analysis: done. WARNING: Zero subjects in group %d.', group); 
            else            
                app.TextArea.FontColor = '0.47,0.67,0.19';            
                app.TextArea.Value = 'Graph theory analysis: done';
            end
        end

        % Button pushed function: VisualiseButton_Graphs
        function VisualiseButton_GraphsPushed(app, event)
            
            %%% Visualise results of graph theory analysis

            cocoa_viz_graph(app.results,app.info);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CocoaboxUIFigure = uifigure('Visible', 'off');
            app.CocoaboxUIFigure.Name = 'Cocoabox';
            app.TabGroup = uitabgroup(app.CocoaboxUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DataimportTab = uitab(app.TabGroup);
            app.DataimportTab.Title = 'Data import';
            app.GridLayoutDataimport = uigridlayout(app.DataimportTab);
            app.DatasourceDropDown = uidropdown(app.GridLayoutDataimport);
            app.DatasourceDropDown.Items = {'import own data', 'import matrix/matrices and generate data', 'generate data from scratch'};
            app.DatasourceDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasourceDropDownValueChanged, true);
            app.DatasourceDropDown.Value = 'import own data';
            app.ImportdataButton = uibutton(app.GridLayoutDataimport, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.DatasourceDropDownLabel = uilabel(app.GridLayoutDataimport);
            app.DatasourceDropDownLabel.Text = 'Data source:';
            app.ImportmatrixmatricesButton = uibutton(app.GridLayoutDataimport, 'push');
            app.ImportmatrixmatricesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmatrixmatricesButtonPushed, true);
            app.ImportmatrixmatricesButton.Visible = 'off';
            app.ImportmatrixmatricesButton.Text = 'Import matrix/matrices';
            app.Panel = uipanel(app.GridLayoutDataimport);
            app.GridLayout11 = uigridlayout(app.Panel);
            app.GridLayout11.Visible = 'off';
            app.NumberofmatricesEditField = uieditfield(app.GridLayout11, 'numeric');
            app.NumberofmatricesEditField.LowerLimitInclusive = 'off';
            app.NumberofmatricesEditField.Limits = [0 Inf];
            app.NumberofmatricesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofmatricesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofmatricesEditFieldValueChanged, true);
            app.NumberofmatricesEditField.Value = 1;
            app.NumberoftimepointsEditField = uieditfield(app.GridLayout11, 'numeric');
            app.NumberoftimepointsEditField.LowerLimitInclusive = 'off';
            app.NumberoftimepointsEditField.Limits = [0 Inf];
            app.NumberoftimepointsEditField.ValueDisplayFormat = '%.0f';
            app.NumberoftimepointsEditField.Value = 1;
            app.NumberofregionsEditField = uieditfield(app.GridLayout11, 'numeric');
            app.NumberofregionsEditField.LowerLimitInclusive = 'off';
            app.NumberofregionsEditField.Limits = [0 Inf];
            app.NumberofregionsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofregionsEditField.Value = 1;
            app.NumberofrealisationsEditField = uieditfield(app.GridLayout11, 'numeric');
            app.NumberofrealisationsEditField.LowerLimitInclusive = 'off';
            app.NumberofrealisationsEditField.Limits = [0 Inf];
            app.NumberofrealisationsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofrealisationsEditField.Value = 1;
            app.NumberoftimepointsEditFieldLabel = uilabel(app.GridLayout11);
            app.NumberoftimepointsEditFieldLabel.Text = 'Number of timepoints:';
            app.NumberofregionsEditFieldLabel = uilabel(app.GridLayout11);
            app.NumberofregionsEditFieldLabel.Text = 'Number of regions:';
            app.NumberofrealisationsEditFieldLabel = uilabel(app.GridLayout11);
            app.NumberofrealisationsEditFieldLabel.Text = 'Number of realisations:';
            app.GraphdensityEditField = uieditfield(app.GridLayout11, 'numeric');
            app.GraphdensityEditField.LowerLimitInclusive = 'off';
            app.GraphdensityEditField.UpperLimitInclusive = 'off';
            app.GraphdensityEditField.Limits = [0 1];
            app.GraphdensityEditField.Value = 0.25;
            app.GraphdensityEditFieldLabel = uilabel(app.GridLayout11);
            app.GraphdensityEditFieldLabel.Text = 'Graph density:';
            app.LargesteigenvalueEditField = uieditfield(app.GridLayout11, 'numeric');
            app.LargesteigenvalueEditField.LowerLimitInclusive = 'off';
            app.LargesteigenvalueEditField.UpperLimitInclusive = 'off';
            app.LargesteigenvalueEditField.Limits = [0 1];
            app.LargesteigenvalueEditField.Tooltip = {''};
            app.LargesteigenvalueEditField.Value = 0.9;
            app.LargesteigenvalueEditFieldLabel = uilabel(app.GridLayout11);
            app.LargesteigenvalueEditFieldLabel.Text = 'Largest eigenvalue:';
            app.NumberofmatricesEditFieldLabel = uilabel(app.GridLayout11);
            app.NumberofmatricesEditFieldLabel.Text = 'Number of matrices:';
            app.symmetricCheckBox = uicheckbox(app.GridLayout11);
            app.symmetricCheckBox.Text = 'symmetric';
            app.autocorrelatedCheckBox = uicheckbox(app.GridLayout11);
            app.autocorrelatedCheckBox.Text = 'autocorrelated';
            app.autocorrelatedCheckBox.Value = true;
            app.GeneratedataButton = uibutton(app.GridLayout11, 'push');
            app.GeneratedataButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratedataButtonPushed, true);
            app.GeneratedataButton.Text = 'Generate data';
            app.DatalabelingPanel = uipanel(app.GridLayoutDataimport);
            app.DatalabelingPanel.Title = 'Data labeling';
            app.GridLayout = uigridlayout(app.DatalabelingPanel);
            app.DatasetlabelEditFieldLabel = uilabel(app.GridLayout);
            app.DatasetlabelEditFieldLabel.Text = 'Dataset label:';
            app.DatasetlabelEditField = uieditfield(app.GridLayout, 'text');
            app.Group1LabelEditFieldLabel = uilabel(app.GridLayout);
            app.Group1LabelEditFieldLabel.Text = 'Group 1 - Label:';
            app.Group1LabelEditField = uieditfield(app.GridLayout, 'text');
            app.Group1LabelEditField.Value = 'group 1';
            app.Group2LabelEditFieldLabel = uilabel(app.GridLayout);
            app.Group2LabelEditFieldLabel.Text = 'Group 2 - Label:';
            app.CountEditField_Group1 = uieditfield(app.GridLayout, 'numeric');
            app.CountEditField_Group1.Limits = [0 Inf];
            app.CountEditField_Group1.ValueDisplayFormat = '%.0f';
            app.Group2LabelEditField = uieditfield(app.GridLayout, 'text');
            app.Group2LabelEditField.Value = 'group 2';
            app.CountEditFieldLabel = uilabel(app.GridLayout);
            app.CountEditFieldLabel.Text = 'Count:';
            app.CountEditField_2Label = uilabel(app.GridLayout);
            app.CountEditField_2Label.Text = 'Count:';
            app.CountEditField_Group2 = uieditfield(app.GridLayout, 'numeric');
            app.CountEditField_Group2.Limits = [0 Inf];
            app.CountEditField_Group2.ValueDisplayFormat = '%.0f';
            app.LabeldataButton = uibutton(app.GridLayout, 'push');
            app.LabeldataButton.ButtonPushedFcn = createCallbackFcn(app, @LabeldataButtonPushed, true);
            app.LabeldataButton.Text = 'Label data';
            app.SubsetselectionPanel = uipanel(app.GridLayoutDataimport);
            app.SubsetselectionPanel.Title = 'Subset selection';
            app.GridLayout6 = uigridlayout(app.SubsetselectionPanel);
            app.TimepointsEditField_Subset = uieditfield(app.GridLayout6, 'text');
            app.TimepointsEditField_Subset.Tooltip = {'Enter indices as comma-separated lists, numeric ranges, or a combination of the two'};
            app.TimepointsSwitch_Subset = uiswitch(app.GridLayout6, 'slider');
            app.TimepointsSwitch_Subset.Items = {'include', 'exclude'};
            app.TimepointsSwitch_Subset.Value = 'include';
            app.RegionsEditField_Subset = uieditfield(app.GridLayout6, 'text');
            app.RegionsEditField_Subset.Tooltip = {'Enter indices as comma-separated lists, numeric ranges, or a combination of the two'};
            app.RegionsSwitch_Subset = uiswitch(app.GridLayout6, 'slider');
            app.RegionsSwitch_Subset.Items = {'include', 'exclude'};
            app.RegionsSwitch_Subset.Value = 'include';
            app.RealisationsEditField_Subset = uieditfield(app.GridLayout6, 'text');
            app.RealisationsEditField_Subset.Tooltip = {'Enter indices as comma-separated lists, numeric ranges, or a combination of the two'};
            app.RealisationsSwitch_Subset = uiswitch(app.GridLayout6, 'slider');
            app.RealisationsSwitch_Subset.Items = {'include', 'exclude'};
            app.RealisationsSwitch_Subset.Value = 'include';
            app.TimepointsEditField_4Label = uilabel(app.GridLayout6);
            app.TimepointsEditField_4Label.Text = 'Timepoints:';
            app.RegionsEditField_4Label = uilabel(app.GridLayout6);
            app.RegionsEditField_4Label.Text = 'Regions:';
            app.RealisationsEditFieldLabel = uilabel(app.GridLayout6);
            app.RealisationsEditFieldLabel.Text = 'Realisations:';
            app.SelectsubsetButton_Subset = uibutton(app.GridLayout6, 'push');
            app.SelectsubsetButton_Subset.ButtonPushedFcn = createCallbackFcn(app, @SelectsubsetButton_SubsetPushed, true);
            app.SelectsubsetButton_Subset.Text = 'Select subset';
            app.ResetButton_Subset = uibutton(app.GridLayout6, 'push');
            app.ResetButton_Subset.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_SubsetPushed, true);
            app.ResetButton_Subset.Tooltip = {'Return to original data'};
            app.ResetButton_Subset.Text = 'Reset';
            app.DimensionalityreductionTab = uitab(app.TabGroup);
            app.DimensionalityreductionTab.Title = 'Dimensionality reduction';
            app.GridLayout5 = uigridlayout(app.DimensionalityreductionTab);
            app.MethodDropDown_DimRed = uidropdown(app.GridLayout5);
            app.MethodDropDown_DimRed.Items = {'PCA', 'ICA', 'NNMF', 'k-means'};
            app.MethodDropDown_DimRed.ValueChangedFcn = createCallbackFcn(app, @MethodDropDown_DimRedValueChanged, true);
            app.MethodDropDown_DimRed.Value = 'PCA';
            app.MethodDropDown_6Label = uilabel(app.GridLayout5);
            app.MethodDropDown_6Label.Text = 'Method:';
            app.NumberofcomponentsEditField_DimRed = uieditfield(app.GridLayout5, 'numeric');
            app.NumberofcomponentsEditField_DimRed.Limits = [1 Inf];
            app.NumberofcomponentsEditField_DimRed.ValueDisplayFormat = '%.0f';
            app.NumberofcomponentsEditField_DimRed.ValueChangedFcn = createCallbackFcn(app, @NumberofcomponentsEditField_DimRedValueChanged, true);
            app.NumberofcomponentsEditField_DimRed.Value = 1;
            app.NumberofcomponentsEditFieldLabel = uilabel(app.GridLayout5);
            app.NumberofcomponentsEditFieldLabel.Text = 'Number of components:';
            app.DimensionDropDown_DimRed = uidropdown(app.GridLayout5);
            app.DimensionDropDown_DimRed.Items = {'temporal', 'spatial'};
            app.DimensionDropDown_DimRed.Value = 'temporal';
            app.DimensionDropDownLabel = uilabel(app.GridLayout5);
            app.DimensionDropDownLabel.Text = 'Dimension:';
            app.ReducedimensionalityButton_DimRed = uibutton(app.GridLayout5, 'push');
            app.ReducedimensionalityButton_DimRed.ButtonPushedFcn = createCallbackFcn(app, @ReducedimensionalityButton_DimRedPushed, true);
            app.ReducedimensionalityButton_DimRed.Text = 'Reduce dimensionality';
            app.ResetButton_DimRed = uibutton(app.GridLayout5, 'push');
            app.ResetButton_DimRed.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_DimRedPushed, true);
            app.ResetButton_DimRed.Tooltip = {'Undo dimensionality reduction'};
            app.ResetButton_DimRed.Text = 'Reset';
            app.VisualisationPanel_2 = uipanel(app.GridLayout5);
            app.VisualisationPanel_2.Title = 'Visualisation';
            app.GridLayout2_3 = uigridlayout(app.VisualisationPanel_2);
            app.MethodDropDownVis_DimRed = uidropdown(app.GridLayout2_3);
            app.MethodDropDownVis_DimRed.Items = {'individual', 'summarised'};
            app.MethodDropDownVis_DimRed.Value = 'individual';
            app.TimepointsEditField_DimRed = uieditfield(app.GridLayout2_3, 'text');
            app.TimepointsEditField_DimRed.Tooltip = {'Enter indices as comma-separated lists, numeric ranges, or a combination of the two'};
            app.TimepointsSwitch_DimRed = uiswitch(app.GridLayout2_3, 'slider');
            app.TimepointsSwitch_DimRed.Items = {'include', 'exclude'};
            app.TimepointsSwitch_DimRed.Value = 'include';
            app.ComponentsEditField_DimRed = uieditfield(app.GridLayout2_3, 'text');
            app.ComponentsEditField_DimRed.Tooltip = {'Enter indices as comma-separated lists, numeric ranges, or a combination of the two'};
            app.RegionsSwitch_DimRed = uiswitch(app.GridLayout2_3, 'slider');
            app.RegionsSwitch_DimRed.Items = {'include', 'exclude'};
            app.RegionsSwitch_DimRed.Value = 'include';
            app.RealisationsEditField_DimRed = uieditfield(app.GridLayout2_3, 'text');
            app.RealisationsEditField_DimRed.Tooltip = {'Enter indices as comma-separated lists, numeric ranges, or a combination of the two'};
            app.RealisationsSwitch_DimRed = uiswitch(app.GridLayout2_3, 'slider');
            app.RealisationsSwitch_DimRed.Items = {'include', 'exclude'};
            app.RealisationsSwitch_DimRed.Value = 'include';
            app.TimepointsEditField_3Label = uilabel(app.GridLayout2_3);
            app.TimepointsEditField_3Label.Text = 'Timepoints:';
            app.ComponentsEditFieldLabel = uilabel(app.GridLayout2_3);
            app.ComponentsEditFieldLabel.Text = 'Components:';
            app.RealisationsEditField_5Label = uilabel(app.GridLayout2_3);
            app.RealisationsEditField_5Label.Text = 'Realisations:';
            app.VisualiseButton_DimRed = uibutton(app.GridLayout2_3, 'push');
            app.VisualiseButton_DimRed.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButton_DimRedPushed, true);
            app.VisualiseButton_DimRed.Text = 'Visualise';
            app.MethodDropDown_8Label = uilabel(app.GridLayout2_3);
            app.MethodDropDown_8Label.Text = 'Method:';
            app.ConnectivitymatricesTab = uitab(app.TabGroup);
            app.ConnectivitymatricesTab.Title = 'Connectivity matrices';
            app.GridLayout7 = uigridlayout(app.ConnectivitymatricesTab);
            app.ConnectivitymeasureDropDown_Matrices = uidropdown(app.GridLayout7);
            app.ConnectivitymeasureDropDown_Matrices.Items = {'Pearson correlation', 'partial Pearson correlation', 'Spearman correlation', 'mutual information', 'Granger causality'};
            app.ConnectivitymeasureDropDown_Matrices.Value = 'Pearson correlation';
            app.ComputeButton_Matrices = uibutton(app.GridLayout7, 'push');
            app.ComputeButton_Matrices.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_MatricesPushed, true);
            app.ComputeButton_Matrices.Text = 'Compute';
            app.ConnectivitymeasureDropDownLabel = uilabel(app.GridLayout7);
            app.ConnectivitymeasureDropDownLabel.Text = 'Connectivity measure:';
            app.VisualisationPanel_Matrices = uipanel(app.GridLayout7);
            app.VisualisationPanel_Matrices.Title = 'Visualisation';
            app.GridLayout10 = uigridlayout(app.VisualisationPanel_Matrices);
            app.OptionDropDown_Matrices = uidropdown(app.GridLayout10);
            app.OptionDropDown_Matrices.Items = {'first matrix', 'number of matrices', 'selected matrices'};
            app.OptionDropDown_Matrices.ValueChangedFcn = createCallbackFcn(app, @OptionDropDown_MatricesValueChanged, true);
            app.OptionDropDown_Matrices.Value = 'first matrix';
            app.OptionDropDownLabel = uilabel(app.GridLayout10);
            app.OptionDropDownLabel.Text = 'Option:';
            app.MatricescountEditField_Matrices = uieditfield(app.GridLayout10, 'numeric');
            app.MatricescountEditField_Matrices.Limits = [1 Inf];
            app.MatricescountEditField_Matrices.Value = 1;
            app.MatricesEditField_Matrices = uieditfield(app.GridLayout10, 'text');
            app.MatricesEditField_Matrices.Tooltip = {'Enter indices as comma-separated lists, numeric ranges, or a combination of the two'};
            app.MatricesEditFieldLabel = uilabel(app.GridLayout10);
            app.MatricesEditFieldLabel.Text = 'Matrices:';
            app.VisualiseButton_Matrices = uibutton(app.GridLayout10, 'push');
            app.VisualiseButton_Matrices.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButton_MatricesPushed, true);
            app.VisualiseButton_Matrices.Text = 'Visualise';
            app.MatricescountEditFieldLabel = uilabel(app.GridLayout10);
            app.MatricescountEditFieldLabel.Text = 'Matrices count:';
            app.StatisticalinferenceTab = uitab(app.TabGroup);
            app.StatisticalinferenceTab.Title = 'Statistical inference';
            app.GridLayout8_2 = uigridlayout(app.StatisticalinferenceTab);
            app.MethodDropDown_Stats = uidropdown(app.GridLayout8_2);
            app.MethodDropDown_Stats.Items = {'p-value masking', 'ROC curve'};
            app.MethodDropDown_Stats.ValueChangedFcn = createCallbackFcn(app, @MethodDropDown_StatsValueChanged, true);
            app.MethodDropDown_Stats.Value = 'p-value masking';
            app.GroupcomparisontestDropDown_Stats = uidropdown(app.GridLayout8_2);
            app.GroupcomparisontestDropDown_Stats.Items = {'Mann-Whitney U-test', 't-test'};
            app.GroupcomparisontestDropDown_Stats.Value = 'Mann-Whitney U-test';
            app.GroupcomparisontestDropDown_2Label = uilabel(app.GridLayout8_2);
            app.GroupcomparisontestDropDown_2Label.Text = 'Group comparison test:';
            app.MultiplecorrectionmethodDropDown_Stats = uidropdown(app.GridLayout8_2);
            app.MultiplecorrectionmethodDropDown_Stats.Items = {'FWE - Hochberg''s procedure', 'FWE - Bonferroni correction', 'FDR', 'none'};
            app.MultiplecorrectionmethodDropDown_Stats.Value = 'FWE - Hochberg''s procedure';
            app.MultiplecorrectionmethodDropDown_2Label = uilabel(app.GridLayout8_2);
            app.MultiplecorrectionmethodDropDown_2Label.Text = 'Multiple correction method:';
            app.alphathresholdEditField_Stats = uieditfield(app.GridLayout8_2, 'numeric');
            app.alphathresholdEditField_Stats.Limits = [0 1];
            app.alphathresholdEditField_Stats.Value = 0.05;
            app.alphathresholdEditField_3Label = uilabel(app.GridLayout8_2);
            app.alphathresholdEditField_3Label.Text = 'alpha threshold:';
            app.FeatureDropDown_Stats = uidropdown(app.GridLayout8_2);
            app.FeatureDropDown_Stats.Items = {'global connectivity', 'typicality of functional connectivity', 'mask connectivity', 'average mask connectivity', 'PCA'};
            app.FeatureDropDown_Stats.ValueChangedFcn = createCallbackFcn(app, @FeatureDropDown_StatsValueChanged, true);
            app.FeatureDropDown_Stats.Enable = 'off';
            app.FeatureDropDown_Stats.Value = 'global connectivity';
            app.FeatureDropDownLabel_Stats = uilabel(app.GridLayout8_2);
            app.FeatureDropDownLabel_Stats.Enable = 'off';
            app.FeatureDropDownLabel_Stats.Text = 'Feature:';
            app.AdditionalmatrixLabel_Stats = uilabel(app.GridLayout8_2);
            app.AdditionalmatrixLabel_Stats.Enable = 'off';
            app.AdditionalmatrixLabel_Stats.Text = 'Additional matrix:';
            app.LoadmatrixButton_Stats = uibutton(app.GridLayout8_2, 'push');
            app.LoadmatrixButton_Stats.ButtonPushedFcn = createCallbackFcn(app, @LoadmatrixButton_StatsPushed, true);
            app.LoadmatrixButton_Stats.Enable = 'off';
            app.LoadmatrixButton_Stats.Text = 'Load matrix';
            app.ComputeButton_Stats = uibutton(app.GridLayout8_2, 'push');
            app.ComputeButton_Stats.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_StatsPushed, true);
            app.ComputeButton_Stats.Text = 'Compute';
            app.VisualiseButton_Stats = uibutton(app.GridLayout8_2, 'push');
            app.VisualiseButton_Stats.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButton_StatsPushed, true);
            app.VisualiseButton_Stats.Text = 'Visualise';
            app.MethodDropDownLabel_2 = uilabel(app.GridLayout8_2);
            app.MethodDropDownLabel_2.Text = 'Method:';
            app.GraphtheoryanalysisTab = uitab(app.TabGroup);
            app.GraphtheoryanalysisTab.Title = 'Graph theory analysis';
            app.GridLayout9 = uigridlayout(app.GraphtheoryanalysisTab);
            app.ThresholddensityEditField_Graphs = uieditfield(app.GridLayout9, 'numeric');
            app.ThresholddensityEditField_Graphs.LowerLimitInclusive = 'off';
            app.ThresholddensityEditField_Graphs.Limits = [0 1];
            app.ThresholddensityEditField_Graphs.Value = 0.25;
            app.ThresholddensityEditFieldLabel = uilabel(app.GridLayout9);
            app.ThresholddensityEditFieldLabel.Text = 'Threshold density:';
            app.alphathresholdEditField_GraphsLabel = uilabel(app.GridLayout9);
            app.alphathresholdEditField_GraphsLabel.Text = 'alpha threshold:';
            app.alphathresholdEditField_Graphs = uieditfield(app.GridLayout9, 'numeric');
            app.alphathresholdEditField_Graphs.Limits = [0 1];
            app.alphathresholdEditField_Graphs.Value = 0.05;
            app.ComputeButton_Graphs = uibutton(app.GridLayout9, 'push');
            app.ComputeButton_Graphs.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_GraphsPushed, true);
            app.ComputeButton_Graphs.Text = 'Compute';
            app.VisualiseButton_Graphs = uibutton(app.GridLayout9, 'push');
            app.VisualiseButton_Graphs.ButtonPushedFcn = createCallbackFcn(app, @VisualiseButton_GraphsPushed, true);
            app.VisualiseButton_Graphs.Text = 'Visualise';
            app.TextArea = uitextarea(app.CocoaboxUIFigure);
            app.Image2_9 = uiimage(app.CocoaboxUIFigure);
            app.Image2_9.ImageSource = 'cobra_logo.png';
            app.Image10 = uiimage(app.CocoaboxUIFigure);
            app.Image10.ImageSource = 'tacr_logo_bw.png';
            % Show the figure after all components are created
            app.CocoaboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cocoabox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CocoaboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CocoaboxUIFigure)
        end
    end
end